classdef Foreground_Segmentator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        InputParametersPanel           matlab.ui.container.Panel
        SceneFolderButton              matlab.ui.control.Button
        selectedscenefolderpathLabel   matlab.ui.control.Label
        VirtualBackgroundButton        matlab.ui.control.Button
        selectedvirtualbackgroundpathLabel  matlab.ui.control.Label
        SelectModeDropDownLabel        matlab.ui.control.Label
        SelectModeDropDown             matlab.ui.control.DropDown
        StartButton                    matlab.ui.control.Button
        OutputFolderButton             matlab.ui.control.Button
        StartatimageEditFieldLabel     matlab.ui.control.Label
        StartatimageEditField          matlab.ui.control.NumericEditField
        StopButton                     matlab.ui.control.Button
        VideoNameLabel                 matlab.ui.control.Label
        VideoNameEditField             matlab.ui.control.EditField
        LoopCheckBox                   matlab.ui.control.CheckBox
        selectedoutputfolderpathLabel  matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        StatusLabel                    matlab.ui.control.Label
        ElapsedTimeLabel               matlab.ui.control.Label
        FramesAnalyzedLabel            matlab.ui.control.Label
        CurrentFrameLabel              matlab.ui.control.Label
        TotalFramesLabel               matlab.ui.control.Label
        LogTextAreaLabel               matlab.ui.control.Label
        LogTextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        exitloop % Description
        videosrc
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SceneFolderButton
        function SceneFolderButtonPushed(app, event)
            
            src=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %drawnow;
            figure(app.UIFigure);            % to show gui in foreground


            app.selectedscenefolderpathLabel.Text=src;  % Sets the label text to be the selected path

           
        end

        % Button pushed function: VirtualBackgroundButton
        function VirtualBackgroundButtonPushed(app, event)
            [bg, path1]= uigetfile({'*.jpg';'*.mp4';'*.avi'});                    % Calls 'uigetdir' to obtain the directory location from the user
            bgpath=strcat(path1,bg);
            %drawnow;
            figure(app.UIFigure);               % to show gui in foreground
            
            app.selectedvirtualbackgroundpathLabel.Text=bgpath;  % Sets the label text to be the selected path

        end

        % Value changed function: SelectModeDropDown
        function SelectModeDropDownValueChanged(app, event)
            render_mode = app.SelectModeDropDown.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %delete log to show
            diary off
            if exist('log.txt', 'file')
                 delete('log.txt');
            end
            diary on
            %dont show warnings on the gui
            warning('off','images:bwfilt:tie');
            
            app.exitloop = 0;
            % Start timer here
            tStart=tic;
            
            app.StatusLabel.Text = 'Status: Running Calculations';
            config              %load config file
            loop=0;
            v=1;
            addpath('bonus');
            store = 0;
            %movie_frames=cell(1, 3000);
            
            %take user input
            src=app.selectedscenefolderpathLabel.Text;
            start = app.StartatimageEditField.Value;
            render_mode=app.SelectModeDropDown.Value;
            bg=app.selectedvirtualbackgroundpathLabel.Text; 
            ir = ImageReader(src, L, R, start, N);
            
            %setup video
            if ~isempty(app.VideoNameEditField.Value) && ~isempty(app.videosrc) %only save video if name is given
                videofull = fullfile(app.videosrc, app.VideoNameEditField.Value);
                movie = VideoWriter(videofull, 'Motion JPEG AVI');
                movie.FrameRate = 10;
                store = 1;
            end
            
            if render_mode == "substitute"
                if contains(bg, 'jpg') | contains(bg, 'png') %Check whether the background is an image or not
                    bg=imread(bg);
                    
                else
                    error('Background image is not .jpg or .png');
                end
            elseif render_mode =="bonus"
                if contains(bg, 'jpg') | contains(bg, 'png')
                    error('Background is in image. Should be a video');
                else
                    bg=VideoReader(bg);
                end
            end
            

            i=0;
            while loop ~= 1
                
                % Get next image tensors
              [left, right, loop]=ir.next();
               
               
              % Generate binary mask
              [mask]=segmentation(left,right); %sumImage);
              
              % always the last frame of the tensor should masked
              frame=left(:,:,(end-2:end));
              frame2=right(:,:,(end-2:end));
              % Render new frame
              if render_mode=="bonus"
                  [result, v] = render_bonus( frame, mask, bg, render_mode, v);
              else
                  disp(render_mode);
                  result = render(frame,mask,bg,render_mode);
              end
              
              %display the frames on the gui
              %left
              imshow(frame,'Parent',app.UIAxes);
              %right
              imshow(frame2,'Parent', app.UIAxes2);
              %processed image
              imshow(result,'Parent',app.UIAxes3);
              drawnow;
              
              if store == 1
                  if ~isempty(result)
                        movie_frames=result;
                        open(movie)
                        writeVideo(movie, movie_frames);
                  else
                    break;
                  end
              end


              if app.exitloop == 1
                 break;
              end
              
              %print some information for the user
               app.CurrentFrameLabel.Text= "Current Frame: " + num2str(i) ;
               app.TotalFramesLabel.Text="Total Frames: ";
               disp("Done Calculating Frame " + num2str(i));
                
                
                %to get console output onto the screen
                diary log.txt
                temp = regexp(fileread('log.txt'), '\r?\n', 'split');
                app.LogTextArea.Value = temp;
                
                
                i = i+1;   
            end
           disp("Calculations completed");
           close(movie);
           if store == 1
               disp("Video exported");
           end


            %display stats
            elapsed_time = toc(tStart);
            app.ElapsedTimeLabel.Text="Elapsed Time:" + elapsed_time;
            app.ElapsedTimeLabel.Visible = 1;
            app.StatusLabel.Text = "Status: Calculations Completed";
            
            app.FramesAnalyzedLabel.Text = "Frames Analyzed: "+ i;
            app.FramesAnalyzedLabel.Visible = 1;
    
            diary off
        end

        % Value changed function: StartatimageEditField
        function StartatimageEditFieldValueChanged(app, event)
            start = app.StartatimageEditField.Value;
            
        end

        % Value changed function: VideoNameEditField
        function VideoNameEditFieldValueChanged(app, event)
            videoname = app.VideoNameEditField.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.exitloop = 1;
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            app.videosrc = uigetdir;  
            figure(app.UIFigure);            % to show gui in foreground
            app.selectedoutputfolderpathLabel.Text = app.videosrc;  % Sets the label text to be the selected path
        end

        % Value changed function: LoopCheckBox
        function LoopCheckBoxValueChanged(app, event)
            value = app.LoopCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Sequence Left')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original Sequence Right')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Masked sequence')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.SceneFolderButton = uibutton(app.InputParametersPanel, 'push');
            app.SceneFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SceneFolderButtonPushed, true);
            app.SceneFolderButton.Text = 'Scene Folder';
            app.selectedscenefolderpathLabel = uilabel(app.InputParametersPanel);
            app.selectedscenefolderpathLabel.Text = 'selected scene folder path';
            app.VirtualBackgroundButton = uibutton(app.InputParametersPanel, 'push');
            app.VirtualBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualBackgroundButtonPushed, true);
            app.VirtualBackgroundButton.Text = 'Virtual Background';
            app.selectedvirtualbackgroundpathLabel = uilabel(app.InputParametersPanel);
            app.selectedvirtualbackgroundpathLabel.Text = 'selected virtual background path';
            app.SelectModeDropDownLabel = uilabel(app.InputParametersPanel);
            app.SelectModeDropDownLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.InputParametersPanel);
            app.SelectModeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute', 'bonus'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModeDropDownValueChanged, true);
            app.SelectModeDropDown.Value = 'foreground';
            app.StartButton = uibutton(app.InputParametersPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OutputFolderButton = uibutton(app.InputParametersPanel, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.StartatimageEditFieldLabel = uilabel(app.InputParametersPanel);
            app.StartatimageEditFieldLabel.Text = 'Start at image';
            app.StartatimageEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.StartatimageEditField.ValueChangedFcn = createCallbackFcn(app, @StartatimageEditFieldValueChanged, true);
            app.StopButton = uibutton(app.InputParametersPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VideoNameLabel = uilabel(app.InputParametersPanel);
            app.VideoNameLabel.Text = 'Video Name';
            app.VideoNameEditField = uieditfield(app.InputParametersPanel, 'text');
            app.VideoNameEditField.ValueChangedFcn = createCallbackFcn(app, @VideoNameEditFieldValueChanged, true);
            app.LoopCheckBox = uicheckbox(app.InputParametersPanel);
            app.LoopCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoopCheckBoxValueChanged, true);
            app.LoopCheckBox.Text = 'Loop';
            app.selectedoutputfolderpathLabel = uilabel(app.InputParametersPanel);
            app.selectedoutputfolderpathLabel.Text = 'selected output folder path';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.StatusLabel = uilabel(app.OutputPanel);
            app.StatusLabel.Text = 'Status';
            app.ElapsedTimeLabel = uilabel(app.OutputPanel);
            app.ElapsedTimeLabel.Tag = 'ElapsedTime';
            app.ElapsedTimeLabel.Visible = 'off';
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.FramesAnalyzedLabel = uilabel(app.OutputPanel);
            app.FramesAnalyzedLabel.Visible = 'off';
            app.FramesAnalyzedLabel.Text = 'Frames Analyzed: ';
            app.CurrentFrameLabel = uilabel(app.OutputPanel);
            app.CurrentFrameLabel.Text = 'Current Frame: ';
            app.TotalFramesLabel = uilabel(app.OutputPanel);
            app.TotalFramesLabel.Text = 'Total Frames: ';
            app.LogTextAreaLabel = uilabel(app.OutputPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.OutputPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Foreground_Segmentator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end