classdef myGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        scenefolderpathButton          matlab.ui.control.Button
        scenefolderpathlabelLabel      matlab.ui.control.Label
        virtualbackgroundpathButton    matlab.ui.control.Button
        virtualbackgroundpathlabelLabel  matlab.ui.control.Label
        LEditFieldLabel                matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        selectrenderingmodeDropDownLabel  matlab.ui.control.Label
        selectrenderingmodeDropDown    matlab.ui.control.DropDown
        numberofframesNEditFieldLabel  matlab.ui.control.Label
        numberofframesNEditField       matlab.ui.control.NumericEditField
        enterstartpointEditField_2Label  matlab.ui.control.Label
        enterstartpointEditField_2     matlab.ui.control.NumericEditField
        REditFieldLabel                matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        RUNButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: scenefolderpathButton
        function scenefolderpathButtonPushed(app, event)
            
            src=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.scenefolderpathlabelLabel.Text=src;  % Sets the label text to be the selected path

           
        end

        % Button pushed function: virtualbackgroundpathButton
        function virtualbackgroundpathButtonPushed(app, event)
            [bg, path1]= uigetfile('*.jpg');                    % Calls 'uigetdir' to obtain the directory location from the user
            bgpath=strcat(path1,bg);
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.virtualbackgroundpathlabelLabel.Text=bgpath;  % Sets the label text to be the selected path

        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            L = app.LEditField.Value;
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            
            
        end

        % Value changed function: selectrenderingmodeDropDown
        function selectrenderingmodeDropDownValueChanged(app, event)
            mode = app.selectrenderingmodeDropDown.Value;
            
        end

        % Callback function
        function LcameraDropDownValueChanged(app, event)
            L_value = app.LcameraDropDown.Value;
            
        end

        % Callback function
        function RcameraDropDownValueChanged(app, event)


        end

        % Value changed function: numberofframesNEditField
        function numberofframesNEditFieldValueChanged(app, event)
            N = app.numberofframesNEditField.Value;
            
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            R = app.LEditField.Value;
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';    
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            loop=0;
            movie_frames=cell(1, 3000);
            N = app.numberofframesNEditField.Value;
            src=app.scenefolderpathlabelLabel.Text;
            L = app.LEditField.Value;
            R = app.REditField.Value;
            start = app.enterstartpointEditField_2.Value;
            mode=app.selectrenderingmodeDropDown.Value;
            bg=app.virtualbackgroundpathlabelLabel.Text; 
            obj=ImageReader(src, L, R, start, N);
            
            i=1;
            while loop ~= 1
                
                [left, right, l]=next(obj);
                loop=l;
                [mask, background]=segmentation(left,right);
                frame=left(:,:,(end-2:end));
                
                
                [result, result2,mask] = render(frame,mask,bg,mode);
              
                imshow(result2,'Parent',app.UIAxes);
                imshow(result,'Parent',app.UIAxes2);
                drawnow;
                movie_frames{i}=result;
                i=i+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.scenefolderpathButton = uibutton(app.UIFigure, 'push');
            app.scenefolderpathButton.ButtonPushedFcn = createCallbackFcn(app, @scenefolderpathButtonPushed, true);
            app.scenefolderpathButton.Text = 'scene folder path';
            app.scenefolderpathlabelLabel = uilabel(app.UIFigure);
            app.scenefolderpathlabelLabel.Text = 'scene folder path label';
            app.virtualbackgroundpathButton = uibutton(app.UIFigure, 'push');
            app.virtualbackgroundpathButton.ButtonPushedFcn = createCallbackFcn(app, @virtualbackgroundpathButtonPushed, true);
            app.virtualbackgroundpathButton.Text = 'virtual background path';
            app.virtualbackgroundpathlabelLabel = uilabel(app.UIFigure);
            app.virtualbackgroundpathlabelLabel.Text = 'virtual background path label';
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.selectrenderingmodeDropDownLabel = uilabel(app.UIFigure);
            app.selectrenderingmodeDropDownLabel.Text = 'select rendering mode';
            app.selectrenderingmodeDropDown = uidropdown(app.UIFigure);
            app.selectrenderingmodeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute'};
            app.selectrenderingmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @selectrenderingmodeDropDownValueChanged, true);
            app.selectrenderingmodeDropDown.Value = 'foreground';
            app.numberofframesNEditFieldLabel = uilabel(app.UIFigure);
            app.numberofframesNEditFieldLabel.Text = 'number of frames N';
            app.numberofframesNEditField = uieditfield(app.UIFigure, 'numeric');
            app.numberofframesNEditField.ValueChangedFcn = createCallbackFcn(app, @numberofframesNEditFieldValueChanged, true);
            app.enterstartpointEditField_2Label = uilabel(app.UIFigure);
            app.enterstartpointEditField_2Label.Text = 'enter start point';
            app.enterstartpointEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'result2')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'result')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end